classdef DroneGCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ControllerAnalysisTab  matlab.ui.container.Tab
        TextArea               matlab.ui.control.TextArea
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        SensorTelemetryTab     matlab.ui.container.Tab
        TextArea_2             matlab.ui.control.TextArea
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes4_2              matlab.ui.control.UIAxes
        UIAxes3_2              matlab.ui.control.UIAxes
        UIAxes2_2              matlab.ui.control.UIAxes
        HardwareTab            matlab.ui.container.Tab
        TextArea_3             matlab.ui.control.TextArea
        HardwareAxes           matlab.ui.control.UIAxes
    end

    
   properties (Access = public)
    % Buffers for the plots
    TimeBuffer = [];
    UBuffer = [];
    
    StatePosBuffer = [];
    StateVelBuffer = [];
    StateYawBuffer = [];
    
    GPSPosBuffer = [];
    GPSVelBuffer = [];
    IMUAccelBuffer = [];
    MagYawBuffer = [];
    
    % Counter to manage the 0.005s vs 0.1s sample rates
    FastCounter = 0; 
end
    
    properties (Access = private)
       SerialDevice        
       HardwareTimer       
       CurrentTime        
       LivePlotLine  
    end
    
    methods (Access = private)
        
        function ReadHardwareData(app)
     % Pull the raw text from the USB and convert to a number
    rawData = readline(app.SerialDevice);
    liveDistance = str2double(rawData);

    % make sure to plot only when data is not nan
    if ~isnan(liveDistance)
        
        % Increment time by the timer period (0.02 seconds)
        app.CurrentTime = app.CurrentTime + 0.02; 
        
        % Add the new point to the existing line
        addpoints(app.LivePlotLine, app.CurrentTime, liveDistance);
        
        % Expand the X-axis so the line continuously scrolls right
        xlim(app.HardwareAxes, [0, max(1, app.CurrentTime)]);
        
        % Command MATLAB to render graphics now, dropping frames if needed to prevent lag
        drawnow limitrate;
    end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%  Safely check what the user selected (Hardware only, simulation or
% both)
    if evalin('base', 'exist(''ModeSelection'', ''var'')')
        runMode = evalin('base', 'ModeSelection');
    else
        runMode = 'Run Both'; 
    end

    % Only start the serial connection if requested
    if strcmp(runMode, 'Hardware Only') || strcmp(runMode, 'Run Both')
        try
            % Open the physical connection
            app.SerialDevice = serialport("COM9", 115200);
            configureTerminator(app.SerialDevice, "LF");
            flush(app.SerialDevice);

            % Reset time tracker
            app.CurrentTime = 0;

            % Clear the old graph and create the high-speed animated line
            cla(app.HardwareAxes);
            app.LivePlotLine = animatedline(app.HardwareAxes, 'Color', 'b', 'LineWidth', 1.5);

            % Create and start the 50Hz background loop
            app.HardwareTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', 0.02, ...
                                      'TimerFcn', @(~,~)ReadHardwareData(app));
            start(app.HardwareTimer);
            
        catch
            % Catch errors (like forgetting to plug in the USB) without crashing
            disp('Warning: ESP32 not detected on COM9. Live hardware tab inactive.');
        end
    end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControllerAnalysisTab = uitab(app.TabGroup);
            app.ControllerAnalysisTab.Title = 'Controller Analysis';
            app.UIAxes = uiaxes(app.ControllerAnalysisTab);
            title(app.UIAxes, 'Control Effort (Thrust, Roll, Pitch, Yaw)')
            xlabel(app.UIAxes, 'time(s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 80];
            app.UIAxes2 = uiaxes(app.ControllerAnalysisTab);
            title(app.UIAxes2, 'Position States (x, y, z)')
            xlabel(app.UIAxes2, 'time(s)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 10];
            app.UIAxes2.YLim = [-1 4.5];
            app.UIAxes3 = uiaxes(app.ControllerAnalysisTab);
            title(app.UIAxes3, 'Velocity States (vx, vy, vz)')
            xlabel(app.UIAxes3, 'time(s)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 10];
            app.UIAxes3.YLim = [-1 6.15];
            app.UIAxes4 = uiaxes(app.ControllerAnalysisTab);
            title(app.UIAxes4, 'Attitude State (Yaw)')
            xlabel(app.UIAxes4, 'time(s)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.XLim = [0 10];
            app.UIAxes4.YLim = [-0.05 0.05];
            app.TextArea = uitextarea(app.ControllerAnalysisTab);
            app.TextArea.Value = {'Controller Graohycal User interface'};
            app.SensorTelemetryTab = uitab(app.TabGroup);
            app.SensorTelemetryTab.Title = 'Sensor Telemetry';
            app.UIAxes2_2 = uiaxes(app.SensorTelemetryTab);
            title(app.UIAxes2_2, 'GPS Velocity')
            xlabel(app.UIAxes2_2, 'time(s)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XLim = [0 10];
            app.UIAxes2_2.YLim = [-1 6.15];
            app.UIAxes3_2 = uiaxes(app.SensorTelemetryTab);
            title(app.UIAxes3_2, 'IMU Acceleration')
            xlabel(app.UIAxes3_2, 'time(s)')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.XLim = [0 10];
            app.UIAxes3_2.YLim = [-4 70];
            app.UIAxes4_2 = uiaxes(app.SensorTelemetryTab);
            title(app.UIAxes4_2, 'Magnetometer Yaw')
            xlabel(app.UIAxes4_2, 'time(s)')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_2.XLim = [0 10];
            app.UIAxes4_2.YLim = [-1 1];
            app.UIAxes_2 = uiaxes(app.SensorTelemetryTab);
            title(app.UIAxes_2, 'GPS & Baro Position')
            xlabel(app.UIAxes_2, 'time(s)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 10];
            app.UIAxes_2.YLim = [-15 15];
            app.TextArea_2 = uitextarea(app.SensorTelemetryTab);
            app.TextArea_2.Value = {'Sensor Graohycal User interface'};
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'HardwareTab';
            app.HardwareAxes = uiaxes(app.HardwareTab);
            title(app.HardwareAxes, 'IR altitude sensor readings')
            xlabel(app.HardwareAxes, 'time(s)')
            ylabel(app.HardwareAxes, 'hight')
            zlabel(app.HardwareAxes, 'Z')
            app.TextArea_3 = uitextarea(app.HardwareTab);
            app.TextArea_3.Value = {'Actual sesnot data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DroneGCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end